classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        UIAxes               matlab.ui.control.UIAxes
        StartButton          matlab.ui.control.Button
        DurationSliderLabel  matlab.ui.control.Label
        DurationSlider       matlab.ui.control.Slider
        TzewSliderLabel      matlab.ui.control.Label
        TzewSlider           matlab.ui.control.Slider
        StopButton           matlab.ui.control.Button
        Label                matlab.ui.control.Label
        refreshButton        matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        function results = updateVal(block, ei)
                %create a run time object that can return the value of the gain block's
                %output and then put the value in a string.
%                 rto = get_param('app_designer/Gain','RuntimeObject');
%                 str = num2str(rto.OutputPort(1).Data);
                    
                    str  = get_param(block.BlockHandle,'Data');
                    app.Label.Text = str;                    
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        load  app.mat

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            value = app.TzewSlider.Value; 
            assignin('base','Tzew0',value);
            load  app.mat
            open_system('app_designer.slx');
            set_param('app_designer','SimulationCommand','start');    
    
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            set_param('app_designer','SimulationCommand','stop');      
        end

        % Button pushed function: refreshButton
        function refreshButtonPushed(app, event)
            rto = get_param('app_designer/Gain','RuntimeObject');
            str = num2str(rto.OutputPort(1).Data);
            app.Label.Text = str;
        end

        % Value changed function: TzewSlider
        function TzewSliderValueChanged(app, event)
            value = app.TzewSlider.Value;
            assignin('base','Tzew0',value);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Twew1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DurationSliderLabel = uilabel(app.UIFigure);
            app.DurationSliderLabel.Text = 'Duration ';
            app.DurationSlider = uislider(app.UIFigure);
            app.DurationSlider.Limits = [0 10000000];
            app.DurationSlider.Value = 1000000;
            app.TzewSliderLabel = uilabel(app.UIFigure);
            app.TzewSliderLabel.Text = 'Tzew';
            app.TzewSlider = uislider(app.UIFigure);
            app.TzewSlider.Limits = [-20 20];
            app.TzewSlider.ValueChangedFcn = createCallbackFcn(app, @TzewSliderValueChanged, true);
            app.TzewSlider.Value = -20;
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Label = uilabel(app.UIFigure);
            app.refreshButton = uibutton(app.UIFigure, 'push');
            app.refreshButton.ButtonPushedFcn = createCallbackFcn(app, @refreshButtonPushed, true);
            app.refreshButton.Text = 'refresh';
        end
    end

    methods (Access = public)

        % Construct app
        function app = App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end